classdef CreditRisk < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Login                          matlab.ui.container.Tab
        LogInLabel                     matlab.ui.control.Label
        LogInButton                    matlab.ui.control.Button
        Password                       matlab.ui.control.EditField
        PasswordLabel                  matlab.ui.control.Label
        UserName                       matlab.ui.control.EditField
        UserNameLabel                  matlab.ui.control.Label
        HomeTab                        matlab.ui.container.Tab
        AddNewUserButton               matlab.ui.control.Button
        CREDITRISKAPPLICATIONLabel     matlab.ui.control.Label
        HowwouldyouliketocalculatecreditriskLabel  matlab.ui.control.Label
        FromUserButton                 matlab.ui.control.Button
        FromDataButton                 matlab.ui.control.Button
        FromDataTab                    matlab.ui.container.Tab
        TheCreditRiskEditField_2       matlab.ui.control.EditField
        TheCreditRiskEditField_2Label  matlab.ui.control.Label
        NumberRealEstateLoansorLinesEditField_2  matlab.ui.control.EditField
        NumberRealEstateLoansorLinesEditField_2Label  matlab.ui.control.Label
        NumberofOpenCreditLoansEditField_2  matlab.ui.control.EditField
        NumberofOpenCreditLoansEditField_2Label  matlab.ui.control.Label
        NumberofOverdueDebtsEditField_2  matlab.ui.control.EditField
        NumberofOverdueDebtsEditField_2Label  matlab.ui.control.Label
        MonthlyIncomeEditField_2       matlab.ui.control.EditField
        MonthlyIncomeEditField_2Label  matlab.ui.control.Label
        DebtRatioEditField_2           matlab.ui.control.EditField
        DebtRatioEditField_2Label      matlab.ui.control.Label
        RevolvingUtilizationofUnsecuredLinesEditField_2  matlab.ui.control.EditField
        RevolvingUtilizationofUnsecuredLinesEditField_2Label  matlab.ui.control.Label
        MainMenuButton                 matlab.ui.control.Button
        ShowResultButton               matlab.ui.control.Button
        IdEditField                    matlab.ui.control.NumericEditField
        IdEditFieldLabel               matlab.ui.control.Label
        FromUserTab                    matlab.ui.container.Tab
        TheCreditRiskEditField         matlab.ui.control.EditField
        TheCreditRiskEditFieldLabel    matlab.ui.control.Label
        MainMenuButton_2               matlab.ui.control.Button
        NumberRealEstateLoansorLinesEditField  matlab.ui.control.NumericEditField
        NumberRealEstateLoansorLinesEditFieldLabel  matlab.ui.control.Label
        NumberofOpenCreditLoansEditField  matlab.ui.control.NumericEditField
        NumberofOpenCreditLoansEditFieldLabel  matlab.ui.control.Label
        NumberofOverdueDebtsEditField  matlab.ui.control.NumericEditField
        NumberofOverdueDebtsEditFieldLabel  matlab.ui.control.Label
        MonthlyIncomeEditField         matlab.ui.control.NumericEditField
        MonthlyIncomeEditFieldLabel    matlab.ui.control.Label
        DebtRatioEditField             matlab.ui.control.NumericEditField
        DebtRatioEditFieldLabel        matlab.ui.control.Label
        RevolvingUtilizationofUnsecuredLinesEditField  matlab.ui.control.NumericEditField
        RevolvingUtilizationofUnsecuredLinesEditFieldLabel  matlab.ui.control.Label
        ShowResultButton_2             matlab.ui.control.Button
        AddUserTab                     matlab.ui.container.Tab
        MainMenuButton_3               matlab.ui.control.Button
        AddButton                      matlab.ui.control.Button
        AddNewUserLabel                matlab.ui.control.Label
        NewPasswordEditField           matlab.ui.control.EditField
        NewPasswordLabel               matlab.ui.control.Label
        NewUserNameEditField           matlab.ui.control.EditField
        NewUserNameLabel               matlab.ui.control.Label
    end

    
    properties (Access = public)
        data; % Description
        f;
        x;
        a;
        b;
        c;
        d;
        e;
        g;
        h;
        userName;
        password;
        excelName;
        loginData;
        n;
        checkPassword;
    end
    
    methods (Access = private)
        
        function results = FindValue(app,x)
            app.data=readtable('projeData.xlsx');
            app.f=readfis('creditRisk.fis');
            app.a=app.data(x,4);
            app.b=app.data(x,7);
            app.c=app.data(x,9);
            app.d=app.data(x,14);
            app.e=app.data(x,10);
            app.g=app.data(x,15);
            app.a=table2array(app.a);
            app.b=table2array(app.b);
            app.c=table2array(app.c);
            app.d=table2array(app.d);
            app.e=table2array(app.e);
            app.g=table2array(app.g);
            app.h=evalfis([app.a app.b app.c app.d app.e app.g],app.f);
        end
        
        function results = FindUserValue(app)
            app.f=readfis('creditRisk.fis');
            app.h=evalfis([app.a app.b app.c app.d app.e app.g],app.f);
        end
        
        function results = checkUser(app)
            [numbers, strings, raw] = xlsread('Login_Data.xlsx');
            lastRow = size(raw, 1);
            app.loginData=readtable('Login_Data.xlsx');
            app.n=1;
            app.userName = app.UserName.Value;
            app.userName = string(app.userName);
            app.password = app.Password.Value;
            app.password = str2double(app.password);
            while app.n<lastRow+1
                app.excelName=app.loginData(app.n,1);
                app.excelName=table2array(app.excelName);
                app.excelName=string(app.excelName);
                if app.userName == app.excelName
                    app.checkPassword=app.loginData(app.n,2);
                    app.checkPassword=table2array(app.checkPassword);
                    break
                else 
                    app.n=app.n+1;
                    
                end
            end
            if app.checkPassword==app.password
                app.TabGroup.SelectedTab=app.HomeTab;
            else
                msgbox('You entered wrong user name or password!');
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: IdEditField
        function IdEditFieldValueChanged(app, event)
            value = app.IdEditField.Value;
            app.x=value;
        end

        % Button pushed function: FromDataButton
        function FromDataButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.FromDataTab ;
        end

        % Button pushed function: ShowResultButton
        function ShowResultButtonPushed(app, event)
            FindValue(app,app.x);
            answera = num2str(app.a);
            app.RevolvingUtilizationofUnsecuredLinesEditField_2.Value = answera;
            answerb = num2str(app.b);
            app.DebtRatioEditField_2.Value = answerb;
            answerc = num2str(app.c);
            app.MonthlyIncomeEditField_2.Value = answerc;
            answerd = num2str(app.d);
            app.NumberofOverdueDebtsEditField_2.Value = answerd;
            answere = num2str(app.e);
            app.NumberofOpenCreditLoansEditField_2.Value = answere;
            answerg = num2str(app.g);
            app.NumberRealEstateLoansorLinesEditField_2.Value = answerg;
            answerh = num2str(app.h);
            app.TheCreditRiskEditField_2.Value = answerh;
        end

        % Callback function
        function ResultEditFieldValueChanged(app, event)
            value = app.ResultEditField.Value;
            value = FindValue(app,app.x);
            
        end

        % Button pushed function: FromUserButton
        function FromUserButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.FromUserTab ;
        end

        % Button pushed function: ShowResultButton_2
        function ShowResultButton_2Pushed(app, event)
            FindUserValue(app);
            answerh = num2str(app.h);
            app.TheCreditRiskEditField.Value = answerh;
        end

        % Value changed function: 
        % RevolvingUtilizationofUnsecuredLinesEditField
        function RevolvingUtilizationofUnsecuredLinesEditFieldValueChanged(app, event)
            value = app.RevolvingUtilizationofUnsecuredLinesEditField.Value;
            app.a = value;
        end

        % Value changed function: DebtRatioEditField
        function DebtRatioEditFieldValueChanged(app, event)
            value = app.DebtRatioEditField.Value;
            app.b = value;
        end

        % Value changed function: MonthlyIncomeEditField
        function MonthlyIncomeEditFieldValueChanged(app, event)
            value = app.MonthlyIncomeEditField.Value;
            app.c = value;
        end

        % Value changed function: NumberofOverdueDebtsEditField
        function NumberofOverdueDebtsEditFieldValueChanged(app, event)
            value = app.NumberofOverdueDebtsEditField.Value;
            app.d = value;
        end

        % Value changed function: NumberofOpenCreditLoansEditField
        function NumberofOpenCreditLoansEditFieldValueChanged(app, event)
            value = app.NumberofOpenCreditLoansEditField.Value;
            app.e = value;
        end

        % Value changed function: 
        % NumberRealEstateLoansorLinesEditField
        function NumberRealEstateLoansorLinesEditFieldValueChanged(app, event)
            value = app.NumberRealEstateLoansorLinesEditField.Value;
            app.g = value;
        end

        % Button pushed function: MainMenuButton
        function MainMenuButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.HomeTab ;
        end

        % Button pushed function: MainMenuButton_2
        function MainMenuButton_2Pushed(app, event)
            app.TabGroup.SelectedTab=app.HomeTab ; 
        end

        % Value changed function: 
        % RevolvingUtilizationofUnsecuredLinesEditField_2
        function RevolvingUtilizationofUnsecuredLinesEditField_2ValueChanged(app, event)
            value = app.RevolvingUtilizationofUnsecuredLinesEditField_2.Value;
            
        end

        % Value changed function: DebtRatioEditField_2
        function DebtRatioEditField_2ValueChanged(app, event)
            value = app.DebtRatioEditField_2.Value;
            
        end

        % Value changed function: MonthlyIncomeEditField_2
        function MonthlyIncomeEditField_2ValueChanged(app, event)
            value = app.MonthlyIncomeEditField_2.Value;
            
        end

        % Value changed function: NumberofOverdueDebtsEditField_2
        function NumberofOverdueDebtsEditField_2ValueChanged(app, event)
            value = app.NumberofOverdueDebtsEditField_2.Value;
            
        end

        % Value changed function: NumberofOpenCreditLoansEditField_2
        function NumberofOpenCreditLoansEditField_2ValueChanged(app, event)
            value = app.NumberofOpenCreditLoansEditField_2.Value;
            
        end

        % Value changed function: 
        % NumberRealEstateLoansorLinesEditField_2
        function NumberRealEstateLoansorLinesEditField_2ValueChanged(app, event)
            value = app.NumberRealEstateLoansorLinesEditField_2.Value;
            
        end

        % Value changed function: TheCreditRiskEditField_2
        function TheCreditRiskEditField_2ValueChanged(app, event)
            value = app.TheCreditRiskEditField_2.Value;
            
        end

        % Value changed function: TheCreditRiskEditField
        function TheCreditRiskEditFieldValueChanged(app, event)
            value = app.TheCreditRiskEditField.Value;
            
        end

        % Button pushed function: LogInButton
        function LogInButtonPushed(app, event)
            app.userName = app.UserName.Value;
            app.password = app.Password.Value;
            checkUser(app);
        end

        % Button pushed function: AddNewUserButton
        function AddNewUserButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.AddUserTab ;
        end

        % Value changed function: NewUserNameEditField
        function NewUserNameEditFieldValueChanged(app, event)
            value = app.NewUserNameEditField.Value            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            newUser=app.NewUserNameEditField.Value;
            newPasssword=app.NewPasswordEditField.Value;
            newUser=string(newUser);
            newPasssword=string(newPasssword);
            x1=[newUser newPasssword];
            writematrix(x1,'Login_Data.xlsx',"WriteMod","append");
            msgbox('The user was added!');
        end

        % Value changed function: NewPasswordEditField
        function NewPasswordEditFieldValueChanged(app, event)
            value = app.NewPasswordEditField.Value;
            
        end

        % Button pushed function: MainMenuButton_3
        function MainMenuButton_3Pushed(app, event)
            app.TabGroup.SelectedTab=app.HomeTab ;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Login = uitab(app.TabGroup);
            app.Login.Title = 'Login';
            app.UserNameLabel = uilabel(app.Login);
            app.UserNameLabel.Text = 'User Name :';
            app.UserName = uieditfield(app.Login, 'text');
            app.PasswordLabel = uilabel(app.Login);
            app.PasswordLabel.Text = 'Password :';
            app.Password = uieditfield(app.Login, 'text');
            app.LogInButton = uibutton(app.Login, 'push');
            app.LogInButton.ButtonPushedFcn = createCallbackFcn(app, @LogInButtonPushed, true);
            app.LogInButton.Text = 'Log In';
            app.LogInLabel = uilabel(app.Login);
            app.LogInLabel.Text = 'Log In';
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.FromDataButton = uibutton(app.HomeTab, 'push');
            app.FromDataButton.ButtonPushedFcn = createCallbackFcn(app, @FromDataButtonPushed, true);
            app.FromDataButton.Text = 'From Data';
            app.FromUserButton = uibutton(app.HomeTab, 'push');
            app.FromUserButton.ButtonPushedFcn = createCallbackFcn(app, @FromUserButtonPushed, true);
            app.FromUserButton.Text = 'From User';
            app.HowwouldyouliketocalculatecreditriskLabel = uilabel(app.HomeTab);
            app.HowwouldyouliketocalculatecreditriskLabel.Text = {'How would you like to calculate credit risk?'; ''};
            app.CREDITRISKAPPLICATIONLabel = uilabel(app.HomeTab);
            app.CREDITRISKAPPLICATIONLabel.Text = 'CREDIT RISK APPLICATION';
            app.AddNewUserButton = uibutton(app.HomeTab, 'push');
            app.AddNewUserButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewUserButtonPushed, true);
            app.AddNewUserButton.Text = 'Add New User';
            app.FromDataTab = uitab(app.TabGroup);
            app.FromDataTab.Title = 'From Data';
            app.IdEditFieldLabel = uilabel(app.FromDataTab);
            app.IdEditFieldLabel.Text = 'Id';
            app.IdEditField = uieditfield(app.FromDataTab, 'numeric');
            app.IdEditField.Limits = [0 10000];
            app.IdEditField.ValueChangedFcn = createCallbackFcn(app, @IdEditFieldValueChanged, true);
            app.ShowResultButton = uibutton(app.FromDataTab, 'push');
            app.ShowResultButton.ButtonPushedFcn = createCallbackFcn(app, @ShowResultButtonPushed, true);
            app.ShowResultButton.Text = 'Show Result';
            app.MainMenuButton = uibutton(app.FromDataTab, 'push');
            app.MainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButtonPushed, true);
            app.MainMenuButton.Text = 'Main Menu';
            app.RevolvingUtilizationofUnsecuredLinesEditField_2Label = uilabel(app.FromDataTab);
            app.RevolvingUtilizationofUnsecuredLinesEditField_2Label.Text = 'Revolving Utilization of Unsecured Lines';
            app.RevolvingUtilizationofUnsecuredLinesEditField_2 = uieditfield(app.FromDataTab, 'text');
            app.RevolvingUtilizationofUnsecuredLinesEditField_2.ValueChangedFcn = createCallbackFcn(app, @RevolvingUtilizationofUnsecuredLinesEditField_2ValueChanged, true);
            app.RevolvingUtilizationofUnsecuredLinesEditField_2.Editable = 'off';
            app.DebtRatioEditField_2Label = uilabel(app.FromDataTab);
            app.DebtRatioEditField_2Label.Text = 'Debt Ratio';
            app.DebtRatioEditField_2 = uieditfield(app.FromDataTab, 'text');
            app.DebtRatioEditField_2.ValueChangedFcn = createCallbackFcn(app, @DebtRatioEditField_2ValueChanged, true);
            app.DebtRatioEditField_2.Editable = 'off';
            app.MonthlyIncomeEditField_2Label = uilabel(app.FromDataTab);
            app.MonthlyIncomeEditField_2Label.Text = 'Monthly Income';
            app.MonthlyIncomeEditField_2 = uieditfield(app.FromDataTab, 'text');
            app.MonthlyIncomeEditField_2.ValueChangedFcn = createCallbackFcn(app, @MonthlyIncomeEditField_2ValueChanged, true);
            app.MonthlyIncomeEditField_2.Editable = 'off';
            app.NumberofOverdueDebtsEditField_2Label = uilabel(app.FromDataTab);
            app.NumberofOverdueDebtsEditField_2Label.Text = 'Number of Overdue Debts';
            app.NumberofOverdueDebtsEditField_2 = uieditfield(app.FromDataTab, 'text');
            app.NumberofOverdueDebtsEditField_2.ValueChangedFcn = createCallbackFcn(app, @NumberofOverdueDebtsEditField_2ValueChanged, true);
            app.NumberofOverdueDebtsEditField_2.Editable = 'off';
            app.NumberofOpenCreditLoansEditField_2Label = uilabel(app.FromDataTab);
            app.NumberofOpenCreditLoansEditField_2Label.Text = 'Number of Open Credit Loans';
            app.NumberofOpenCreditLoansEditField_2 = uieditfield(app.FromDataTab, 'text');
            app.NumberofOpenCreditLoansEditField_2.ValueChangedFcn = createCallbackFcn(app, @NumberofOpenCreditLoansEditField_2ValueChanged, true);
            app.NumberofOpenCreditLoansEditField_2.Editable = 'off';
            app.NumberRealEstateLoansorLinesEditField_2Label = uilabel(app.FromDataTab);
            app.NumberRealEstateLoansorLinesEditField_2Label.Text = 'Number Real Estate Loans or Lines';
            app.NumberRealEstateLoansorLinesEditField_2 = uieditfield(app.FromDataTab, 'text');
            app.NumberRealEstateLoansorLinesEditField_2.ValueChangedFcn = createCallbackFcn(app, @NumberRealEstateLoansorLinesEditField_2ValueChanged, true);
            app.NumberRealEstateLoansorLinesEditField_2.Editable = 'off';
            app.TheCreditRiskEditField_2Label = uilabel(app.FromDataTab);
            app.TheCreditRiskEditField_2Label.Text = 'The Credit Risk';
            app.TheCreditRiskEditField_2 = uieditfield(app.FromDataTab, 'text');
            app.TheCreditRiskEditField_2.ValueChangedFcn = createCallbackFcn(app, @TheCreditRiskEditField_2ValueChanged, true);
            app.TheCreditRiskEditField_2.Editable = 'off';
            app.FromUserTab = uitab(app.TabGroup);
            app.FromUserTab.Title = 'From User';
            app.ShowResultButton_2 = uibutton(app.FromUserTab, 'push');
            app.ShowResultButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowResultButton_2Pushed, true);
            app.ShowResultButton_2.Text = 'Show Result';
            app.RevolvingUtilizationofUnsecuredLinesEditFieldLabel = uilabel(app.FromUserTab);
            app.RevolvingUtilizationofUnsecuredLinesEditFieldLabel.Text = 'Revolving Utilization of Unsecured Lines';
            app.RevolvingUtilizationofUnsecuredLinesEditField = uieditfield(app.FromUserTab, 'numeric');
            app.RevolvingUtilizationofUnsecuredLinesEditField.Limits = [0 4];
            app.RevolvingUtilizationofUnsecuredLinesEditField.ValueChangedFcn = createCallbackFcn(app, @RevolvingUtilizationofUnsecuredLinesEditFieldValueChanged, true);
            app.DebtRatioEditFieldLabel = uilabel(app.FromUserTab);
            app.DebtRatioEditFieldLabel.Text = 'Debt Ratio';
            app.DebtRatioEditField = uieditfield(app.FromUserTab, 'numeric');
            app.DebtRatioEditField.Limits = [0 4];
            app.DebtRatioEditField.ValueChangedFcn = createCallbackFcn(app, @DebtRatioEditFieldValueChanged, true);
            app.MonthlyIncomeEditFieldLabel = uilabel(app.FromUserTab);
            app.MonthlyIncomeEditFieldLabel.Text = 'Monthly Income';
            app.MonthlyIncomeEditField = uieditfield(app.FromUserTab, 'numeric');
            app.MonthlyIncomeEditField.Limits = [0 20000];
            app.MonthlyIncomeEditField.ValueChangedFcn = createCallbackFcn(app, @MonthlyIncomeEditFieldValueChanged, true);
            app.NumberofOverdueDebtsEditFieldLabel = uilabel(app.FromUserTab);
            app.NumberofOverdueDebtsEditFieldLabel.Text = 'Number of Overdue Debts';
            app.NumberofOverdueDebtsEditField = uieditfield(app.FromUserTab, 'numeric');
            app.NumberofOverdueDebtsEditField.Limits = [0 17];
            app.NumberofOverdueDebtsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofOverdueDebtsEditFieldValueChanged, true);
            app.NumberofOpenCreditLoansEditFieldLabel = uilabel(app.FromUserTab);
            app.NumberofOpenCreditLoansEditFieldLabel.Text = 'Number of Open Credit Loans';
            app.NumberofOpenCreditLoansEditField = uieditfield(app.FromUserTab, 'numeric');
            app.NumberofOpenCreditLoansEditField.Limits = [0 53];
            app.NumberofOpenCreditLoansEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofOpenCreditLoansEditFieldValueChanged, true);
            app.NumberRealEstateLoansorLinesEditFieldLabel = uilabel(app.FromUserTab);
            app.NumberRealEstateLoansorLinesEditFieldLabel.Text = 'Number Real Estate Loans or Lines';
            app.NumberRealEstateLoansorLinesEditField = uieditfield(app.FromUserTab, 'numeric');
            app.NumberRealEstateLoansorLinesEditField.Limits = [0 21];
            app.NumberRealEstateLoansorLinesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberRealEstateLoansorLinesEditFieldValueChanged, true);
            app.NumberRealEstateLoansorLinesEditField.Tooltip = {''; ''};
            app.MainMenuButton_2 = uibutton(app.FromUserTab, 'push');
            app.MainMenuButton_2.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButton_2Pushed, true);
            app.MainMenuButton_2.Text = 'Main Menu';
            app.TheCreditRiskEditFieldLabel = uilabel(app.FromUserTab);
            app.TheCreditRiskEditFieldLabel.Text = 'The Credit Risk';
            app.TheCreditRiskEditField = uieditfield(app.FromUserTab, 'text');
            app.TheCreditRiskEditField.ValueChangedFcn = createCallbackFcn(app, @TheCreditRiskEditFieldValueChanged, true);
            app.TheCreditRiskEditField.Editable = 'off';
            app.AddUserTab = uitab(app.TabGroup);
            app.AddUserTab.Title = 'AddUser';
            app.NewUserNameLabel = uilabel(app.AddUserTab);
            app.NewUserNameLabel.Text = 'New User Name :';
            app.NewUserNameEditField = uieditfield(app.AddUserTab, 'text');
            app.NewUserNameEditField.ValueChangedFcn = createCallbackFcn(app, @NewUserNameEditFieldValueChanged, true);
            app.NewPasswordLabel = uilabel(app.AddUserTab);
            app.NewPasswordLabel.Text = 'New Password :';
            app.NewPasswordEditField = uieditfield(app.AddUserTab, 'text');
            app.NewPasswordEditField.ValueChangedFcn = createCallbackFcn(app, @NewPasswordEditFieldValueChanged, true);
            app.AddNewUserLabel = uilabel(app.AddUserTab);
            app.AddNewUserLabel.Text = 'Add New User';
            app.AddButton = uibutton(app.AddUserTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.MainMenuButton_3 = uibutton(app.AddUserTab, 'push');
            app.MainMenuButton_3.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButton_3Pushed, true);
            app.MainMenuButton_3.Text = 'Main Menu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CreditRisk

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end